classdef varforce_drive_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        varforcecal                matlab.ui.Figure
        text_expt_duration_2       matlab.ui.control.Label
        lbl_beadradius_3           matlab.ui.control.Label
        lbl_beadradius_2           matlab.ui.control.Label
        VideoControlPanel          matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        lbl_fps_2                  matlab.ui.control.Label
        edit_fps                   matlab.ui.control.EditField
        lbl_fps                    matlab.ui.control.Label
        DegaussPanel_2             matlab.ui.container.Panel
        text27                     matlab.ui.control.Label
        popupmenu_deg_loc          matlab.ui.control.DropDown
        checkbox_full_degauss      matlab.ui.control.CheckBox
        edit_deg_freq              matlab.ui.control.EditField
        deg_freq_text              matlab.ui.control.Label
        edit_deg_tau               matlab.ui.control.EditField
        deg_tau_text               matlab.ui.control.Label
        checkbox_degauss           matlab.ui.control.CheckBox
        lbl_samplefreq_2           matlab.ui.control.Label
        text_title_2               matlab.ui.control.Label
        degauss_now_button         matlab.ui.control.Button
        edit_calibum               matlab.ui.control.EditField
        lbl_calibum                matlab.ui.control.Label
        text_expt_duration         matlab.ui.control.Label
        edit_filename              matlab.ui.control.EditField
        checkbox_save_metadata     matlab.ui.control.CheckBox
        edit_sequences             matlab.ui.control.EditField
        text_sequences             matlab.ui.control.Label
        text_geometry              matlab.ui.control.Label
        popupmenu_geometry         matlab.ui.control.DropDown
        text_title                 matlab.ui.control.Label
        edit_bead_radius           matlab.ui.control.EditField
        lbl_beadradius             matlab.ui.control.Label
        lbl_calibname              matlab.ui.control.Label
        popupmenu_calibrators      matlab.ui.control.DropDown
        edit_pulsewidths           matlab.ui.control.EditField
        edit_voltages              matlab.ui.control.EditField
        pushbutton_close           matlab.ui.control.Button
        pushbutton_start           matlab.ui.control.Button
        lbl_pulsewidths            matlab.ui.control.Label
        lbl_input_voltages         matlab.ui.control.Label
        edit_daq_frequency         matlab.ui.control.EditField
        lbl_samplefreq             matlab.ui.control.Label
        lbl_daqboard               matlab.ui.control.Label
        popupmenu_daqboard         matlab.ui.control.DropDown
        edit_calibrator_viscosity  matlab.ui.control.EditField
        lbl_calib_visc             matlab.ui.control.Label
    end

    
    methods (Access = private)
        function listbox_pulsewidths_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end
        
        function listbox_voltages_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end
        
        function logentry(app, txt)
                logtime = clock;
                logtimetext = [ '(' num2str(logtime(1),  '%04i') '.' ...
                               num2str(logtime(2),        '%02i') '.' ...
                               num2str(logtime(3),        '%02i') ', ' ...
                               num2str(logtime(4),        '%02i') ':' ...
                               num2str(logtime(5),        '%02i') ':' ...
                               num2str(round(logtime(6)), '%02i') ') '];
                 headertext = [logtimetext 'varforce_drive_gui: '];
            
                 fprintf('%s%s\n', headertext, txt);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function varforce_drive_gui_OpeningFcn(app, varargin)
            % --- Executes just before varforce_drive_gui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.varforcecal, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to varforce_drive_gui (see VARARGIN)
            
                % Choose default command line output for varforce_drive_gui
                handles.output = hObject;
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Button pushed function: degauss_now_button
        function degauss_now_button_Callback(app, event)
            % --- Executes on button press in degauss_now_button.
            % % % % % % % % % % % % % % % % % % % %
            %  funtion imediatly degausses pole tip with a 5 volt maximum degauss. does
            %  not overwrite metadata file. Useful for complete degauss in advance of
            %  expiriment
            %  sets all params = to those that will provide a full degauss. basicaly a
            %  hack that enters the same things the user could but without making them
            %  take the time or have to reset the params. also pervents writing over
            %  data file with the record of a degauss pulse.
            % % % % % % % % % % % % % % % % % % % %
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                params.deg_loc = char('beginning');
                params.myDAQid              = char('PCIe-6738');
                params.DAQ_sampling_rate    = 100000;
                params.NRepeats             = 1;
                pole_geometry               = get(handles.popupmenu_geometry, 'String');
                pole_geometry_idx           = get(handles.popupmenu_geometry, 'Value');
                params.my_pole_geometry     = char(pole_geometry(pole_geometry_idx));
                params.degauss              = 'on';
                params.deg_tau              = str2num(get(handles.edit_deg_tau, 'String'));
                params.deg_freq             = str2num(get(handles.edit_deg_freq, 'String'));
                params.voltages             = [5 0];
                params.pulse_widths         = [0.5 0.5];
                params.duration             = .1;
                params.calibrator_viscosity = [];
                params.bead_radius          = [];
                params.calibum              = [];
                params.fps                  = str2num(get(handles.edit_fps, 'String'));
            
                logentry(app, 'Starting Degauss run...');
                params = varforce_drive(params);
                pause(params.duration);
                logentry(app, 'Degauss run is finished.');
        end

        % Value changed function: edit_pulsewidths
        function edit_pulsewidths_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                duration = sum(str2num(get(handles.edit_pulsewidths, 'String')));
                numSequences = str2num(get(handles.edit_sequences, 'String'));
                set(handles.lbl_duration, 'String', num2str(duration*numSequences));
        end

        % Value changed function: edit_sequences
        function edit_sequences_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                duration = sum(str2num(get(handles.edit_pulsewidths, 'String')));
                numSequences = str2num(get(handles.edit_sequences, 'String'));
                set(handles.lbl_duration, 'String', num2str(duration*numSequences));
        end

        % Button pushed function: pushbutton_close
        function pushbutton_close_Callback(app, event)
            % --- Executes on button press in pushbutton_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                    close(varforce_drive_gui);
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            % --- Executes on button press in pushbutton_start.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                DAQid = get(handles.popupmenu_daqboard, 'String');
                DAQid_idx = get(handles.popupmenu_daqboard, 'Value');
                params.myDAQid = char(DAQid(DAQid_idx));
            
                params.DAQ_sampling_rate = str2num(get(handles.edit_daq_frequency, 'String'));
                params.NRepeats = str2num(get(handles.edit_sequences, 'String'));
                pole_geometry = get(handles.popupmenu_geometry, 'String');
                pole_geometry_idx = get(handles.popupmenu_geometry, 'Value');
                params.my_pole_geometry = char(pole_geometry(pole_geometry_idx));
            
                deg_loc = get(handles.popupmenu_deg_loc, 'String');
                deg_loc_idx = get(handles.popupmenu_deg_loc, 'Value');
                params.deg_loc = char(deg_loc(deg_loc_idx));
            
                if get(handles.checkbox_full_degauss, 'Value')
                    params.degauss      = 'on';
                    params.deg_tau      = str2num(get(handles.edit_deg_tau, 'String'));
                    params.deg_freq     = str2num(get(handles.edit_deg_freq, 'String'));
                    params.voltages     = [5 0]; % cleaning up with max voltage case
                    params.pulse_widths = [0.5 0.5];
                    params              = varforce_drive(params);
                    logentry(app, 'Full degauss done.');
                end
            
                params.voltages             = str2num(get(handles.edit_voltages, 'String'));
                params.pulse_widths         = str2num(get(handles.edit_pulsewidths, 'String'));
                params.duration             = str2num(get(handles.lbl_duration, 'String'));
                params.calibrator_viscosity = str2num(get(handles.edit_calibrator_viscosity, 'String'));
                params.bead_radius          = str2num(get(handles.edit_bead_radius, 'String'));
                params.calibum              = str2num(get(handles.edit_calibum, 'String'));
                params.deg_tau              = str2num(get(handles.edit_deg_tau, 'String'));
                params.deg_freq             = str2num(get(handles.edit_deg_freq, 'String'));
                params.fps                  = str2num(get(handles.edit_fps, 'String'));
            
                if get(handles.checkbox_degauss, 'Value');
                    params.degauss = 'on';
                else
                    params.degauss = 'off';
                end
            
                % Starting the run by sending information to varforce_drive function.
                logentry(app, 'Starting DAC run...');
                params = varforce_drive(params);
                logentry(app, 'DAC run is finished.');
            
                if get(handles.checkbox_save_metadata, 'Value')
                    filename = get(handles.edit_filename, 'String');
            
                    save(filename, '-struct', 'params');
            
                    logentry(app, ['Saved metadata to file: ' filename '.']);
            
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.varforcecal = uifigure('Visible', 'off');
            colormap(app.varforcecal, 'parula');
            app.varforcecal.Name = 'Variable Force Calibration GUI';
            app.varforcecal.Tag = 'varforcecal';
            app.lbl_calib_visc = uilabel(app.varforcecal);
            app.lbl_calib_visc.Tag = 'lbl_calib_visc';
            app.lbl_calib_visc.Text = 'Calibrator Viscosity [Pa s]:';
            app.edit_calibrator_viscosity = uieditfield(app.varforcecal, 'text');
            app.edit_calibrator_viscosity.Tag = 'edit_calibrator_viscosity';
            app.edit_calibrator_viscosity.Value = '0.023';
            app.popupmenu_daqboard = uidropdown(app.varforcecal);
            app.popupmenu_daqboard.Items = {'daqtest', 'PCIe-6738'};
            app.popupmenu_daqboard.Tag = 'popupmenu_daqboard';
            app.popupmenu_daqboard.Value = 'daqtest';
            app.lbl_daqboard = uilabel(app.varforcecal);
            app.lbl_daqboard.Tag = 'lbl_daqboard';
            app.lbl_daqboard.Text = 'DAq Board:';
            app.lbl_samplefreq = uilabel(app.varforcecal);
            app.lbl_samplefreq.Tag = 'lbl_samplefreq';
            app.lbl_samplefreq.Text = 'DAq Sampling Frequency:';
            app.edit_daq_frequency = uieditfield(app.varforcecal, 'text');
            app.edit_daq_frequency.Tag = 'edit_daq_frequency';
            app.edit_daq_frequency.Value = '100000';
            app.lbl_input_voltages = uilabel(app.varforcecal);
            app.lbl_input_voltages.Tag = 'lbl_input_voltages';
            app.lbl_input_voltages.Text = 'Input Voltages [V]:';
            app.lbl_pulsewidths = uilabel(app.varforcecal);
            app.lbl_pulsewidths.Tag = 'lbl_pulsewidths';
            app.lbl_pulsewidths.Text = 'Pulse Widths [s]:';
            app.pushbutton_start = uibutton(app.varforcecal, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Tag = 'pushbutton_start';
            app.pushbutton_start.Text = 'Start Experiment';
            app.pushbutton_close = uibutton(app.varforcecal, 'push');
            app.pushbutton_close.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_close_Callback, true);
            app.pushbutton_close.Tag = 'pushbutton_close';
            app.pushbutton_close.Text = 'Close';
            app.edit_voltages = uieditfield(app.varforcecal, 'text');
            app.edit_voltages.Tag = 'edit_voltages';
            app.edit_voltages.Value = '[0 3 5]';
            app.edit_pulsewidths = uieditfield(app.varforcecal, 'text');
            app.edit_pulsewidths.ValueChangedFcn = createCallbackFcn(app, @edit_pulsewidths_Callback, true);
            app.edit_pulsewidths.Tag = 'edit_pulsewidths';
            app.edit_pulsewidths.Value = '[1 1 1]';
            app.popupmenu_calibrators = uidropdown(app.varforcecal);
            app.popupmenu_calibrators.Items = {'2.0M sucrose', '2.5M sucrose', 'Karo', 'Other'};
            app.popupmenu_calibrators.Tag = 'popupmenu_calibrators';
            app.popupmenu_calibrators.Value = '2.0M sucrose';
            app.lbl_calibname = uilabel(app.varforcecal);
            app.lbl_calibname.Tag = 'lbl_calibname';
            app.lbl_calibname.Text = 'Calibrator Name:';
            app.lbl_beadradius = uilabel(app.varforcecal);
            app.lbl_beadradius.Tag = 'lbl_beadradius';
            app.lbl_beadradius.Text = 'Bead Radius: ';
            app.edit_bead_radius = uieditfield(app.varforcecal, 'text');
            app.edit_bead_radius.Tag = 'edit_bead_radius';
            app.edit_bead_radius.Value = '0.5';
            app.text_title = uilabel(app.varforcecal);
            app.text_title.Tag = 'text_title';
            app.text_title.Text = '3DFM Variable Force Calibration (Drive GUI)';
            app.popupmenu_geometry = uidropdown(app.varforcecal);
            app.popupmenu_geometry.Items = {'pole1-flat', 'pole4-flat', 'wakeforest612', '3pole-246', '3pole-135', '3pole-351', '3pole-513', '4pole-1245', '4pole-4512', 'SAP'};
            app.popupmenu_geometry.Tag = 'popupmenu_geometry';
            app.popupmenu_geometry.Value = 'pole1-flat';
            app.text_geometry = uilabel(app.varforcecal);
            app.text_geometry.Tag = 'text_geometry';
            app.text_geometry.Text = 'Pole Geometry: ';
            app.text_sequences = uilabel(app.varforcecal);
            app.text_sequences.Tag = 'text_sequences';
            app.text_sequences.Text = 'N Time Sequences:';
            app.edit_sequences = uieditfield(app.varforcecal, 'text');
            app.edit_sequences.ValueChangedFcn = createCallbackFcn(app, @edit_sequences_Callback, true);
            app.edit_sequences.Tag = 'edit_sequences';
            app.edit_sequences.Value = '1';
            app.checkbox_save_metadata = uicheckbox(app.varforcecal);
            app.checkbox_save_metadata.Tag = 'checkbox_save_metadata';
            app.checkbox_save_metadata.Text = 'Save run to file';
            app.checkbox_save_metadata.Value = true;
            app.edit_filename = uieditfield(app.varforcecal, 'text');
            app.edit_filename.Tag = 'edit_filename';
            app.edit_filename.Value = 'test.vfd.mat';
            app.text_expt_duration = uilabel(app.varforcecal);
            app.text_expt_duration.Tag = 'text_expt_duration';
            app.text_expt_duration.Text = 'Duration of Experiment: 6 seconds';
            app.lbl_calibum = uilabel(app.varforcecal);
            app.lbl_calibum.Tag = 'lbl_calibum';
            app.lbl_calibum.Text = 'Microns per Pixel: ';
            app.edit_calibum = uieditfield(app.varforcecal, 'text');
            app.edit_calibum.Tag = 'edit_calibum';
            app.edit_calibum.Value = '0.152';
            app.degauss_now_button = uibutton(app.varforcecal, 'push');
            app.degauss_now_button.ButtonPushedFcn = createCallbackFcn(app, @degauss_now_button_Callback, true);
            app.degauss_now_button.Tag = 'degauss_now_button';
            app.degauss_now_button.Text = 'Degauss Now';
            app.text_title_2 = uilabel(app.varforcecal);
            app.text_title_2.Tag = 'text_title';
            app.text_title_2.Text = 'Version 2025';
            app.lbl_samplefreq_2 = uilabel(app.varforcecal);
            app.lbl_samplefreq_2.Tag = 'lbl_samplefreq';
            app.lbl_samplefreq_2.Text = 'Hz';
            app.DegaussPanel_2 = uipanel(app.varforcecal);
            app.DegaussPanel_2.Title = 'Degauss';
            app.checkbox_degauss = uicheckbox(app.DegaussPanel_2);
            app.checkbox_degauss.Tag = 'checkbox_degauss';
            app.checkbox_degauss.Text = 'Degauss between sequences';
            app.checkbox_degauss.Value = true;
            app.deg_tau_text = uilabel(app.DegaussPanel_2);
            app.deg_tau_text.Tag = 'deg_tau_text';
            app.deg_tau_text.Text = 'Degauss Time Constant';
            app.edit_deg_tau = uieditfield(app.DegaussPanel_2, 'text');
            app.edit_deg_tau.Tag = 'edit_deg_tau';
            app.edit_deg_tau.Value = '.0012';
            app.deg_freq_text = uilabel(app.DegaussPanel_2);
            app.deg_freq_text.Tag = 'deg_freq_text';
            app.deg_freq_text.Text = 'Degauss Frequency';
            app.edit_deg_freq = uieditfield(app.DegaussPanel_2, 'text');
            app.edit_deg_freq.Tag = 'edit_deg_freq';
            app.edit_deg_freq.Value = '10000';
            app.checkbox_full_degauss = uicheckbox(app.DegaussPanel_2);
            app.checkbox_full_degauss.Tag = 'checkbox_full_degauss';
            app.checkbox_full_degauss.Text = 'Full degauss before experiment';
            app.checkbox_full_degauss.Value = true;
            app.popupmenu_deg_loc = uidropdown(app.DegaussPanel_2);
            app.popupmenu_deg_loc.Items = {'end', 'beginning'};
            app.popupmenu_deg_loc.Tag = 'popupmenu_deg_loc';
            app.popupmenu_deg_loc.Value = 'end';
            app.text27 = uilabel(app.DegaussPanel_2);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Degauss Location';
            app.VideoControlPanel = uipanel(app.varforcecal);
            app.VideoControlPanel.Title = 'Video Control';
            app.lbl_fps = uilabel(app.VideoControlPanel);
            app.lbl_fps.Tag = 'lbl_fps';
            app.lbl_fps.Text = 'Frames Per Second';
            app.edit_fps = uieditfield(app.VideoControlPanel, 'text');
            app.edit_fps.Tag = 'edit_fps';
            app.edit_fps.Value = '120';
            app.lbl_fps_2 = uilabel(app.VideoControlPanel);
            app.lbl_fps_2.Tag = 'lbl_fps';
            app.lbl_fps_2.Text = 'Camera';
            app.DropDown = uidropdown(app.VideoControlPanel);
            app.DropDown.Items = {'GS3', 'FL3'};
            app.DropDown.Value = 'GS3';
            app.lbl_beadradius_2 = uilabel(app.varforcecal);
            app.lbl_beadradius_2.Tag = 'lbl_beadradius';
            app.lbl_beadradius_2.Text = 'µm';
            app.lbl_beadradius_3 = uilabel(app.varforcecal);
            app.lbl_beadradius_3.Tag = 'lbl_beadradius';
            app.lbl_beadradius_3.Text = 'µm/pixel';
            app.text_expt_duration_2 = uilabel(app.varforcecal);
            app.text_expt_duration_2.Tag = 'text_expt_duration';
            app.text_expt_duration_2.Text = 'Save Path';
            % Show the figure after all components are created
            app.varforcecal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = varforce_drive_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.varforcecal)

                % Execute the startup function
                runStartupFcn(app, @(app)varforce_drive_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.varforcecal)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.varforcecal)
        end
    end
end