classdef varforce_drive_synced_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        varforcecal                matlab.ui.Figure
        lbl_save_path              matlab.ui.control.Label
        lbl_um_per_p_units         matlab.ui.control.Label
        lbl_bead_radius_units      matlab.ui.control.Label
        VideoControlPanel          matlab.ui.container.Panel
        dropdown_camera            matlab.ui.control.DropDown
        lbl_camera                 matlab.ui.control.Label
        edit_fps                   matlab.ui.control.EditField
        lbl_fps                    matlab.ui.control.Label
        DegaussControlPanel        matlab.ui.container.Panel
        lbl_degauss_location       matlab.ui.control.Label
        dropdown_degauss_location  matlab.ui.control.DropDown
        checkbox_full_degauss      matlab.ui.control.CheckBox
        edit_degauss_frequency     matlab.ui.control.EditField
        lbl_degauss_frequency      matlab.ui.control.Label
        edit_degauss_time          matlab.ui.control.EditField
        lbl_degauss_time           matlab.ui.control.Label
        checkbox_degauss           matlab.ui.control.CheckBox
        lbl_daq_freq_units         matlab.ui.control.Label
        lbl_title_version          matlab.ui.control.Label
        degauss_now_button         matlab.ui.control.Button
        edit_um_per_p              matlab.ui.control.EditField
        lbl_um_per_p               matlab.ui.control.Label
        text_expt_duration         matlab.ui.control.Label
        edit_filename              matlab.ui.control.EditField
        checkbox_save_file         matlab.ui.control.CheckBox
        edit_sequences             matlab.ui.control.EditField
        lbl_sequences              matlab.ui.control.Label
        lbl_pole_geometry          matlab.ui.control.Label
        dropdown_pole_geometry     matlab.ui.control.DropDown
        lbl_title                  matlab.ui.control.Label
        edit_bead_radius           matlab.ui.control.EditField
        lbl_bead_radius            matlab.ui.control.Label
        lbl_calibname              matlab.ui.control.Label
        dropdown_calibname         matlab.ui.control.DropDown
        edit_pulsewidths           matlab.ui.control.EditField
        edit_input_voltages        matlab.ui.control.EditField
        pushbutton_close           matlab.ui.control.Button
        pushbutton_start           matlab.ui.control.Button
        lbl_pulsewidths            matlab.ui.control.Label
        lbl_input_voltages         matlab.ui.control.Label
        edit_daq_freq              matlab.ui.control.EditField
        lbl_daq_freq               matlab.ui.control.Label
        lbl_daq_board              matlab.ui.control.Label
        dropdown_daq_board         matlab.ui.control.DropDown
        edit_calibrator_viscosity  matlab.ui.control.EditField
        lbl_calibrator_viscosity   matlab.ui.control.Label
    end

    properties (Access = private)
        videoFuture   % future object for video recording
        daqFuture     % future object for DAQ run
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function varforce_drive_gui_OpeningFcn(app, varargin)
            % Ensure the app appears on screen when run
            movegui(app.varforcecal, 'onscreen');
        
            % Create GUIDE-style callback arguments (for compatibility)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
        
            % Store default command line output
            handles.output = hObject;
        
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: degauss_now_button
        function degauss_now_button_Callback(app, event)
            % Executes when Degauss Now button is pressed.
            % Immediately degausses the pole tip with a 5 V maximum degauss.
            % Does not overwrite the metadata file.
            % Useful for performing a complete degauss before an experiment.
            
            % Set degauss parameters
            params.deg_loc              = 'beginning';
            params.myDAQid              = 'PCIe-6738';
            params.DAQ_sampling_rate    = 100000;
            params.NRepeats             = 1;
            
            % Pole geometry
            params.my_pole_geometry     = app.dropdown_pole_geometry.Value;
            
            % Degauss settings
            params.degauss              = 'on';
            params.deg_tau              = app.edit_degauss_time.Value;
            params.deg_freq             = app.edit_degauss_frequency.Value;
            params.voltages             = [5 0];
            params.pulse_widths         = [0.5 0.5];
            params.duration             = 0.1;
            
            % Calibration fields (unused here)
            params.calibrator_viscosity = [];
            params.bead_radius          = [];
            params.calibum              = [];
            
            % Frame rate
            params.fps                  = str2double(app.edit_fps.Value);
        
            % Run degauss
            print_message('Starting Degauss run...');
            params = varforce_drive(params, app.edit_filename.Value);
            pause(params.duration);
            print_message('Degauss run is finished.');
        end

        % Value changed function: edit_pulsewidths
        function edit_pulsewidths_Callback(app, event)
            % Get pulse widths as numbers (assumes space/comma-separated input)
            pulseWidths = str2num(app.edit_pulsewidths.Value);  %#ok<ST2NM>
            duration = sum(pulseWidths);
        
            % Get number of sequences
            numSequences = str2double(app.edit_sequences.Value);
        
            % Update the duration label
            totalDuration = duration * numSequences;          
            %app.lbl_duration.Text = num2str(totalDuration);
        end

        % Value changed function: edit_sequences
        function edit_sequences_Callback(app, event)
            % Get pulse widths from edit field (assumes space/comma-separated numbers)
            pulseWidths = str2num(app.edit_pulsewidths.Value); %#ok<ST2NM>
            duration = sum(pulseWidths);
            
            % Get number of sequences
            numSequences = str2double(app.edit_sequences.Value);
            
            % Update the duration label
            app.text_expt_duration.Text = num2str(duration * numSequences);
        end

        % Button pushed function: pushbutton_close
        function pushbutton_close_Callback(app, event)
           delete(app);
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            % Executes when the start button is pressed.
            fprintf('========== STARTING EXPERIMENT ==========')
            % Select DAQ board
            params.myDAQid = app.dropdown_daq_board.Value;  % Store selected DAQ board
            
            % DAQ parameters
            params.DAQ_sampling_rate = str2double(app.edit_daq_freq.Value);  % Sampling rate in Hz
            params.NRepeats          = str2double(app.edit_sequences.Value);  % Number of repeats
            
            % Pole geometry selection
            params.my_pole_geometry = app.dropdown_pole_geometry.Value;  % Selected pole geometry (string)
            
            % Degauss location selection
            params.deg_loc = app.dropdown_degauss_location.Value;  % Selected degauss location (string)
            
            % Perform full degauss if checkbox is selected
            if app.checkbox_full_degauss.Value
                
                % Enable degauss
                print_message('<<<<< Running initial degauss >>>>>');
                params.degauss = 'on';
                
                % Degauss parameters from user input
                params.deg_tau  = str2double(app.edit_degauss_time.Value);    % Time constant
                params.deg_freq = str2double(app.edit_degauss_frequency.Value);   % Frequency
                
                % Fixed degauss pulse for cleaning
                params.voltages     = [5 0];       % Max voltage followed by 0
                params.pulse_widths = [0.5 0.5];   % Pulse widths in seconds
                params.fps          = 120;         % Not used, but here to prevent errors
                
                % Execute the degauss sequence
                params = varforce_drive(params, app.edit_filename.Value);
                print_message('<<<<< Initial degauss complete >>>>>');
            end

            % Read user inputs from GUI and store in params
            % Multi-value inputs (arrays entered as space/comma-separated strings)
            params.voltages     = str2num(app.edit_input_voltages.Value);     %#ok<ST2NM>
            params.pulse_widths = str2num(app.edit_pulsewidths.Value);  %#ok<ST2NM>
            
            % Single-value numeric inputs
            %params.duration             = str2double(app.lbl_duration.Text);  % Total duration
            params.calibrator_viscosity = str2double(app.edit_calibrator_viscosity.Value);
            params.bead_radius          = str2double(app.edit_bead_radius.Value);
            params.calibum              = str2double(app.edit_um_per_p.Value);
            params.deg_tau              = str2double(app.edit_degauss_time.Value);
            params.deg_freq             = str2double(app.edit_degauss_frequency.Value);
            params.fps                  = str2double(app.edit_fps.Value);
            params.camera               = app.dropdown_camera.Value;
            
            % Get degauss parameter from checkbox
            if app.checkbox_degauss.Value
                params.degauss = 'on';
            else
                params.degauss = 'off';
            end

            % Copy all needed variables into local workspace
            filename    = app.edit_filename.Value;   % filename from edit field
            videoFile   = filename;                  % adjusted file name for video TODO xxx
            paramsCopy  = params;                    % assume you have this struct
            totalFrames = paramsCopy.fps * 8;        % 8 second video TODO xxx
            
            % Start parallel pool if not already running
            if isempty(gcp('nocreate'))
                parpool('IdleTimeout', 120);
            end
            
            % Display a message in main MATLAB session
            disp('Running parallel processes...');
            
            % temp xxx run them just outright, not in parallel
            % record_synced_video(videoFile, params.camera, totalFrames, params.fps, 10)


            % Launch two functions in parallel with arguments
            % future1 = parfeval(@record_synced_video, 1, videoFile, paramsCopy.camera, totalFrames, paramsCopy.fps, 15);
            % future2 = parfeval(@varforce_drive, 1, paramsCopy, filename);
            
            % Optionally, process results as they finish
            % [completedIdx1, videoInfo] = fetchNext({future1, future2});
            % [completedIdx2, outParams] = fetchNext({future1, future2});
            
            disp('Parallel processes finished.');
        
            %temp
            %outParams = params;

            % Save params to file, if requested
            if app.checkbox_save_file.Value
                save(filename, '-struct', 'params');                       % Save params struct to .mat file
                print_message(['Saved metadata to file: ' filename '.']);  % Log the action
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.varforcecal = uifigure('Visible', 'off');
            colormap(app.varforcecal, 'parula');
            app.varforcecal.Name = 'Variable Force Calibration GUI';
            app.varforcecal.Tag = 'varforcecal';
            app.lbl_calibrator_viscosity = uilabel(app.varforcecal);
            app.lbl_calibrator_viscosity.Tag = 'lbl_calib_visc';
            app.lbl_calibrator_viscosity.Text = 'Calibrator Viscosity [Pa s]:';
            app.edit_calibrator_viscosity = uieditfield(app.varforcecal, 'text');
            app.edit_calibrator_viscosity.Tag = 'edit_calibrator_viscosity';
            app.edit_calibrator_viscosity.Value = '0.023';
            app.dropdown_daq_board = uidropdown(app.varforcecal);
            app.dropdown_daq_board.Items = {'daqtest', 'PCIe-6738'};
            app.dropdown_daq_board.Tag = 'popupmenu_daqboard';
            app.dropdown_daq_board.Value = 'daqtest';
            app.lbl_daq_board = uilabel(app.varforcecal);
            app.lbl_daq_board.Tag = 'lbl_daqboard';
            app.lbl_daq_board.Text = 'DAq Board:';
            app.lbl_daq_freq = uilabel(app.varforcecal);
            app.lbl_daq_freq.Tag = 'lbl_samplefreq';
            app.lbl_daq_freq.Text = 'DAq Sampling Frequency:';
            app.edit_daq_freq = uieditfield(app.varforcecal, 'text');
            app.edit_daq_freq.Tag = 'edit_daq_frequency';
            app.edit_daq_freq.Value = '100000';
            app.lbl_input_voltages = uilabel(app.varforcecal);
            app.lbl_input_voltages.Tag = 'lbl_input_voltages';
            app.lbl_input_voltages.Text = 'Input Voltages [V]:';
            app.lbl_pulsewidths = uilabel(app.varforcecal);
            app.lbl_pulsewidths.Tag = 'lbl_pulsewidths';
            app.lbl_pulsewidths.Text = 'Pulse Widths [s]:';
            app.pushbutton_start = uibutton(app.varforcecal, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Tag = 'pushbutton_start';
            app.pushbutton_start.Text = 'Start Experiment';
            app.pushbutton_close = uibutton(app.varforcecal, 'push');
            app.pushbutton_close.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_close_Callback, true);
            app.pushbutton_close.Tag = 'pushbutton_close';
            app.pushbutton_close.Text = 'Close';
            app.edit_input_voltages = uieditfield(app.varforcecal, 'text');
            app.edit_input_voltages.Tag = 'edit_voltages';
            app.edit_input_voltages.Value = '[0 3 5]';
            app.edit_pulsewidths = uieditfield(app.varforcecal, 'text');
            app.edit_pulsewidths.ValueChangedFcn = createCallbackFcn(app, @edit_pulsewidths_Callback, true);
            app.edit_pulsewidths.Tag = 'edit_pulsewidths';
            app.edit_pulsewidths.Value = '[1 1 1]';
            app.dropdown_calibname = uidropdown(app.varforcecal);
            app.dropdown_calibname.Items = {'2.0M sucrose', '2.5M sucrose', 'Karo', 'Other'};
            app.dropdown_calibname.Tag = 'popupmenu_calibrators';
            app.dropdown_calibname.Value = '2.0M sucrose';
            app.lbl_calibname = uilabel(app.varforcecal);
            app.lbl_calibname.Tag = 'lbl_calibname';
            app.lbl_calibname.Text = 'Calibrator Name:';
            app.lbl_bead_radius = uilabel(app.varforcecal);
            app.lbl_bead_radius.Tag = 'lbl_beadradius';
            app.lbl_bead_radius.Text = 'Bead Radius: ';
            app.edit_bead_radius = uieditfield(app.varforcecal, 'text');
            app.edit_bead_radius.Tag = 'edit_bead_radius';
            app.edit_bead_radius.Value = '0.5';
            app.lbl_title = uilabel(app.varforcecal);
            app.lbl_title.Tag = 'text_title';
            app.lbl_title.Text = '3DFM Variable Force Calibration (Drive GUI)';
            app.dropdown_pole_geometry = uidropdown(app.varforcecal);
            app.dropdown_pole_geometry.Items = {'pole1-flat', 'pole4-flat', 'wakeforest612', '3pole-246', '3pole-135', '3pole-351', '3pole-513', '4pole-1245', '4pole-4512', 'SAP'};
            app.dropdown_pole_geometry.Tag = 'popupmenu_geometry';
            app.dropdown_pole_geometry.Value = 'pole1-flat';
            app.lbl_pole_geometry = uilabel(app.varforcecal);
            app.lbl_pole_geometry.Tag = 'text_geometry';
            app.lbl_pole_geometry.Text = 'Pole Geometry: ';
            app.lbl_sequences = uilabel(app.varforcecal);
            app.lbl_sequences.Tag = 'text_sequences';
            app.lbl_sequences.Text = 'N Time Sequences:';
            app.edit_sequences = uieditfield(app.varforcecal, 'text');
            app.edit_sequences.ValueChangedFcn = createCallbackFcn(app, @edit_sequences_Callback, true);
            app.edit_sequences.Tag = 'edit_sequences';
            app.edit_sequences.Value = '1';
            app.checkbox_save_file = uicheckbox(app.varforcecal);
            app.checkbox_save_file.Tag = 'checkbox_save_metadata';
            app.checkbox_save_file.Text = 'Save run to file';
            app.checkbox_save_file.Value = true;
            app.edit_filename = uieditfield(app.varforcecal, 'text');
            app.edit_filename.Tag = 'edit_filename';
            app.edit_filename.Value = 'test.vfd.mat';
            app.text_expt_duration = uilabel(app.varforcecal);
            app.text_expt_duration.Tag = 'text_expt_duration';
            app.text_expt_duration.Text = 'Duration of Experiment: 6 seconds';
            app.lbl_um_per_p = uilabel(app.varforcecal);
            app.lbl_um_per_p.Tag = 'lbl_calibum';
            app.lbl_um_per_p.Text = 'Microns per Pixel: ';
            app.edit_um_per_p = uieditfield(app.varforcecal, 'text');
            app.edit_um_per_p.Tag = 'edit_calibum';
            app.edit_um_per_p.Value = '0.152';
            app.degauss_now_button = uibutton(app.varforcecal, 'push');
            app.degauss_now_button.ButtonPushedFcn = createCallbackFcn(app, @degauss_now_button_Callback, true);
            app.degauss_now_button.Tag = 'degauss_now_button';
            app.degauss_now_button.Text = 'Degauss Now';
            app.lbl_title_version = uilabel(app.varforcecal);
            app.lbl_title_version.Tag = 'text_title';
            app.lbl_title_version.Text = 'Version 2025';
            app.lbl_daq_freq_units = uilabel(app.varforcecal);
            app.lbl_daq_freq_units.Tag = 'lbl_samplefreq';
            app.lbl_daq_freq_units.Text = 'Hz';
            app.DegaussControlPanel = uipanel(app.varforcecal);
            app.DegaussControlPanel.Title = 'Degauss';
            app.checkbox_degauss = uicheckbox(app.DegaussControlPanel);
            app.checkbox_degauss.Tag = 'checkbox_degauss';
            app.checkbox_degauss.Text = 'Degauss between sequences';
            app.checkbox_degauss.Value = true;
            app.lbl_degauss_time = uilabel(app.DegaussControlPanel);
            app.lbl_degauss_time.Tag = 'deg_tau_text';
            app.lbl_degauss_time.Text = 'Degauss Time Constant';
            app.edit_degauss_time = uieditfield(app.DegaussControlPanel, 'text');
            app.edit_degauss_time.Tag = 'edit_deg_tau';
            app.edit_degauss_time.Value = '.0012';
            app.lbl_degauss_frequency = uilabel(app.DegaussControlPanel);
            app.lbl_degauss_frequency.Tag = 'deg_freq_text';
            app.lbl_degauss_frequency.Text = 'Degauss Frequency';
            app.edit_degauss_frequency = uieditfield(app.DegaussControlPanel, 'text');
            app.edit_degauss_frequency.Tag = 'edit_deg_freq';
            app.edit_degauss_frequency.Value = '10000';
            app.checkbox_full_degauss = uicheckbox(app.DegaussControlPanel);
            app.checkbox_full_degauss.Tag = 'checkbox_full_degauss';
            app.checkbox_full_degauss.Text = 'Full degauss before experiment';
            app.checkbox_full_degauss.Value = true;
            app.dropdown_degauss_location = uidropdown(app.DegaussControlPanel);
            app.dropdown_degauss_location.Items = {'end', 'beginning'};
            app.dropdown_degauss_location.Tag = 'popupmenu_deg_loc';
            app.dropdown_degauss_location.Value = 'end';
            app.lbl_degauss_location = uilabel(app.DegaussControlPanel);
            app.lbl_degauss_location.Tag = 'text27';
            app.lbl_degauss_location.Text = 'Degauss Location';
            app.VideoControlPanel = uipanel(app.varforcecal);
            app.VideoControlPanel.Title = 'Video Control';
            app.lbl_fps = uilabel(app.VideoControlPanel);
            app.lbl_fps.Tag = 'lbl_fps';
            app.lbl_fps.Text = 'Frames Per Second';
            app.edit_fps = uieditfield(app.VideoControlPanel, 'text');
            app.edit_fps.Tag = 'edit_fps';
            app.edit_fps.Value = '30';
            app.lbl_camera = uilabel(app.VideoControlPanel);
            app.lbl_camera.Tag = 'lbl_fps';
            app.lbl_camera.Text = 'Camera';
            app.dropdown_camera = uidropdown(app.VideoControlPanel);
            app.dropdown_camera.Items = {'GS3', 'FL3'};
            app.dropdown_camera.Value = 'FL3';
            app.lbl_bead_radius_units = uilabel(app.varforcecal);
            app.lbl_bead_radius_units.Tag = 'lbl_beadradius';
            app.lbl_bead_radius_units.Text = 'µm';
            app.lbl_um_per_p_units = uilabel(app.varforcecal);
            app.lbl_um_per_p_units.Tag = 'lbl_beadradius';
            app.lbl_um_per_p_units.Text = 'µm/pixel';
            app.lbl_save_path = uilabel(app.varforcecal);
            app.lbl_save_path.Tag = 'text_expt_duration';
            app.lbl_save_path.Text = 'Save Path';
            % Show the figure after all components are created
            app.varforcecal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = varforce_drive_synced_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.varforcecal)

                % Execute the startup function
                runStartupFcn(app, @(app)varforce_drive_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.varforcecal)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.varforcecal)
        end
    end
end